classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ApodisationSigmaEditField       matlab.ui.control.NumericEditField
        ApodisationSigmaEditFieldLabel  matlab.ui.control.Label
        PixMicroConversionEditField     matlab.ui.control.NumericEditField
        PixMicroConversionEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexEditField        matlab.ui.control.NumericEditField
        RefractiveIndexEditFieldLabel   matlab.ui.control.Label
        NumericalApertureEditField      matlab.ui.control.NumericEditField
        NumericalApertureEditFieldLabel  matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        CellWidthVarEditField           matlab.ui.control.NumericEditField
        CellWidthVarEditFieldLabel      matlab.ui.control.Label
        CellWidthEditField              matlab.ui.control.NumericEditField
        CellWidthEditFieldLabel         matlab.ui.control.Label
        CellLengthVarEditField          matlab.ui.control.NumericEditField
        CellLengthVarEditFieldLabel     matlab.ui.control.Label
        CellLengthEditField             matlab.ui.control.NumericEditField
        CellLengthEditFieldLabel        matlab.ui.control.Label
        numberEditField                 matlab.ui.control.NumericEditField
        numberEditFieldLabel            matlab.ui.control.Label
        imagenumberSlider               matlab.ui.control.Slider
        imagenumberSliderLabel          matlab.ui.control.Label
        DirEditField                    matlab.ui.control.EditField
        DirEditFieldLabel               matlab.ui.control.Label
        AbsolutepathforrealimagesforreferenceTextArea  matlab.ui.control.TextArea
        AbsolutepathforrealimagesforreferenceTextAreaLabel  matlab.ui.control.Label
        mediamultiplierSlider           matlab.ui.control.Slider
        mediamultiplierSliderLabel      matlab.ui.control.Label
        cellmultiplierSlider            matlab.ui.control.Slider
        cellmultiplierSliderLabel       matlab.ui.control.Label
        devicemultiplierSlider          matlab.ui.control.Slider
        devicemultiplierSliderLabel     matlab.ui.control.Label
        sigmaSlider                     matlab.ui.control.Slider
        sigmaSliderLabel                matlab.ui.control.Label
        scenenumberSlider               matlab.ui.control.Slider
        scenenumberSliderLabel          matlab.ui.control.Label
        defocusSlider                   matlab.ui.control.Slider
        defocusSliderLabel              matlab.ui.control.Label
        PointSpreadFunctionLabel_2      matlab.ui.control.Label
        CellParametersLabel             matlab.ui.control.Label
        TrenchParametersLabel           matlab.ui.control.Label
        TrenchWidthEditField            matlab.ui.control.NumericEditField
        TrenchWidthEditFieldLabel       matlab.ui.control.Label
        TrenchLengthEditField           matlab.ui.control.NumericEditField
        TrenchLengthEditFieldLabel      matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        GenerateButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        DrawMediaButton                 matlab.ui.control.Button
        DrawCellButton                  matlab.ui.control.Button
        LoadImagesButton                matlab.ui.control.Button
        SubmitButton                    matlab.ui.control.Button
        DrawDeviceButton                matlab.ui.control.Button
        UIAxesPlot2                     matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxesPlot1                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DeviceRoIs = []; % Description
        MediaRoIs = [];
        CellRoIs = [];
        normalImageAbsolute = zeros(2,2);
        min_sigma = 0;
        scene_no = 0;
        noisy_img = []; 
        real_resize = [];
        mean_errors, media_errors, cell_errors, device_errors, mean_var_errors, media_var_errors, cell_var_errors, device_var_errors = [];
        mean_error, media_error, cell_error, device_error, mean_var_error, media_var_error, cell_var_error, device_var_error = 0;
    end
    
    methods (Access = private)
        
        function result = normalize99(app, Y)
            X = Y;
           percentile_1 = prctile(X, 0.01, "all");
           %fprintf('x = %.5f \n', percentile_1);
           percentile_99 = prctile(X, 99.99, "all");
           %fprintf('x = %.5f \n', percentile_99);
           % Normalize and scale the data
           normalized_data = double(X - percentile_1) / double(percentile_99 - percentile_1);
           %fprintf("%.2f", normalized_data);
           normalized_data = normalized_data .* 255;
           
           % Round the normalized data to the nearest integer
           normalized_data = round(normalized_data);
           
           % Map values below 1st percentile to 0 and above 99th percentile to 255
           normalized_data(normalized_data < 0) = 0;
           normalized_data(normalized_data > 255) = 255;
           
           result = normalized_data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            
            absPath = app.AbsolutepathforrealimagesforreferenceTextArea.Value{1};
            imageAbsolute = fullfile(absPath); % absolute path to the image file
            app.normalImageAbsolute = app.normalize99(imread(imageAbsolute));
            imshow(app.normalImageAbsolute, [], 'Parent',app.UIAxes2);
        end

        % Button pushed function: DrawDeviceButton
        function DrawDeviceButtonPushed(app, event)
            roi = drawfreehand(app.UIAxes2,"Color",'red');
            mask = createMask(roi);
            if (isempty(app.DeviceRoIs))
                app.DeviceRoIs = mask;
            else
                app.DeviceRoIs = app.DeviceRoIs | mask;
            end
        end

        % Button pushed function: DrawMediaButton
        function DrawMediaButtonPushed(app, event)
            roi = drawfreehand(app.UIAxes2,"Color",'blue');
            mask = createMask(roi);
            if (isempty(app.MediaRoIs))
                app.MediaRoIs = mask;
            else
                app.MediaRoIs = app.MediaRoIs | mask;
            end
        end

        % Button pushed function: DrawCellButton
        function DrawCellButtonPushed(app, event)
            roi = drawfreehand(app.UIAxes2,"Color",'green');
            mask = createMask(roi);
            if (isempty(app.CellRoIs))
                app.CellRoIs = mask;
            else
                app.CellRoIs= app.CellRoIs | mask;
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            terminate(pyenv);
            pyenv('Version', ... 
            '/home/ameyasu/cuda_ws/src/SyMBac/.env/bin/python3.10', ... 
            'ExecutionMode','OutOfProcess');
            real_image = py.numpy.array(int8(app.normalImageAbsolute));
            device_label = py.numpy.array(int8(app.DeviceRoIs));
            cell_label = py.numpy.array(int8(app.CellRoIs));
            media_label = py.numpy.array(int8(app.MediaRoIs));
            trench_length = app.TrenchLengthEditField.Value;
            trench_width = app.TrenchWidthEditField.Value;
            cell_length = app.CellLengthEditField.Value;
            cell_length_var = app.CellLengthVarEditField.Value;
            cell_width = app.CellWidthEditField.Value;
            cell_width_var = app.CellWidthVarEditField.Value;
            radius = app.RadiusEditField.Value;
            wavelength = app.WavelengthEditField.Value;
            numerical_aperture = app.NumericalApertureEditField.Value;
            refractive_index = app.RefractiveIndexEditField.Value;
            pix2mic = app.PixMicroConversionEditField.Value;
            apodisation_sigma = app.ApodisationSigmaEditField.Value;
            [app.min_sigma, app.scene_no] = pyrunfile("./accept_intensities.py", ["min_sigma", "scene_no"], real_image=real_image, device_label=device_label, cell_label=cell_label, media_label=media_label, trench_length = py.float(trench_length), trench_width=py.float(trench_width), cell_length=py.float(cell_length), cell_length_var=py.float(cell_length_var), cell_width=py.float(cell_width), cell_width_var=py.float(cell_width_var), radius=py.int(radius), wavelength=py.float(wavelength), numerical_aperture=py.float(numerical_aperture), refractive_index=py.float(refractive_index), pix2mic=py.float(pix2mic), apodisation_sigma=py.int(apodisation_sigma));
            
            app.sigmaSlider.Limits = [app.min_sigma 20*app.min_sigma];
            app.scenenumberSlider.Limits(2) = int32(app.scene_no) - 1;
               
            app.devicemultiplierSlider.Value = 72;
            app.cellmultiplierSlider.Value = 10;
            app.mediamultiplierSlider.Value = 40;
            app.sigmaSlider.Value = app.min_sigma;
            app.scenenumberSlider.Value = 0;
            app.defocusSlider.Value = 1.0;


            [app.noisy_img, app.real_resize, app.mean_error, app.media_error, app.cell_error, app.device_error, app.mean_var_error, app.media_var_error, app.cell_var_error, app.device_var_error] = pyrunfile("./accept_optimal_params.py", ["noisy_img", "real_resize", "mean_error", "media_error", "cell_error", "device_error", "mean_var_error", "media_var_error", "cell_var_error", "device_var_error"], media_multiplier=py.float(app.mediamultiplierSlider.Value), cell_multiplier=py.float(app.cellmultiplierSlider.Value), device_multiplier=py.float(app.devicemultiplierSlider.Value), sigma=py.float(app.sigmaSlider.Value), scene_no=py.int(app.scenenumberSlider.Value), match_fourier=true, match_histogram=false, match_noise=true, debug_plot=true, MATLAB_access=true, defocus=py.float(app.defocusSlider.Value), save_dir=".");
            
            app.noisy_img = app.normalize99(double(app.noisy_img));

            imshow(app.noisy_img, [], "Parent", app.UIAxes1);

            app.mean_errors = [app.mean_errors, double(app.mean_error(1))];
            app.media_errors = [app.media_errors, double(app.media_error(1))];
            app.cell_errors = [app.cell_errors, double(app.cell_error(1))];
            app.device_errors = [app.device_errors, double(app.device_error(1))];

            app.mean_var_errors = [app.mean_var_errors, double(app.mean_var_error(1))];
            app.media_var_errors = [app.media_var_errors, double(app.media_var_error(1))];
            app.cell_var_errors = [app.cell_var_errors, double(app.cell_var_error(1))];
            app.device_var_errors = [app.device_var_errors, double(app.device_var_error(1))];

            plot(app.UIAxesPlot1, 1:length(app.mean_errors), app.mean_errors, "Color", "green", "DisplayName", "Mean Errors");
            hold(app.UIAxesPlot1, 'on');
            plot(app.UIAxesPlot1, 1:length(app.mean_errors), app.media_errors, "Color", "blue", "DisplayName", "Media Errors");
            hold(app.UIAxesPlot1, 'on');
            plot(app.UIAxesPlot1, 1:length(app.mean_errors), app.cell_errors, "Color", "red", "DisplayName", "Cell Errors");
            hold(app.UIAxesPlot1, 'on');
            plot(app.UIAxesPlot1, 1:length(app.mean_errors), app.device_errors, "Color", "cyan", "DisplayName", "Device Errors");
            hold(app.UIAxesPlot1, 'on');
            legend(app.UIAxesPlot1, 'show');
            hold(app.UIAxesPlot1, 'off');

            plot(app.UIAxesPlot2, 1:length(app.mean_errors), app.mean_var_errors, "Color", "green", "DisplayName", "Mean Var Errors");
            hold(app.UIAxesPlot2, 'on');
            plot(app.UIAxesPlot2, 1:length(app.mean_errors), app.media_var_errors, "Color", "blue", "DisplayName", "Media Var Errors");
            hold(app.UIAxesPlot2, 'on');
            plot(app.UIAxesPlot2, 1:length(app.mean_errors), app.cell_var_errors, "Color", "red", "DisplayName", "Cell Var Errors");
            hold(app.UIAxesPlot2, 'on');
            plot(app.UIAxesPlot2, 1:length(app.mean_errors), app.device_var_errors, "Color", "cyan", "DisplayName", "Device Var Errors");
            hold(app.UIAxesPlot2, 'on');          
            legend(app.UIAxesPlot2, 'show');
            hold(app.UIAxesPlot2, 'off');
        end

        % Callback function: cellmultiplierSlider, defocusSlider, 
        % ...and 5 other components
        function scenenumberSliderValueChanged(app, event)

            [app.noisy_img, app.real_resize, app.mean_error, app.media_error, app.cell_error, app.device_error, app.mean_var_error, app.media_var_error, app.cell_var_error, app.device_var_error] = pyrunfile("./accept_optimal_params.py", ["noisy_img", "real_resize", "mean_error", "media_error", "cell_error", "device_error", "mean_var_error", "media_var_error", "cell_var_error", "device_var_error"], media_multiplier=py.float(app.mediamultiplierSlider.Value), cell_multiplier=py.float(app.cellmultiplierSlider.Value), device_multiplier=py.float(app.devicemultiplierSlider.Value), sigma=py.float(app.sigmaSlider.Value), scene_no=py.int(app.scenenumberSlider.Value), match_fourier=true, match_histogram=false, match_noise=false, debug_plot=true, MATLAB_access=true, defocus=py.float(app.defocusSlider.Value), save_dir=".");
            app.noisy_img = app.normalize99(double(app.noisy_img));

            imshow(app.noisy_img, [], "Parent", app.UIAxes1);

            app.mean_errors = [app.mean_errors, double(app.mean_error(1))];
            app.media_errors = [app.media_errors, double(app.media_error(1))];
            app.cell_errors = [app.cell_errors, double(app.cell_error(1))];
            app.device_errors = [app.device_errors, double(app.device_error(1))];

            app.mean_var_errors = [app.mean_var_errors, double(app.mean_var_error(1))];
            app.media_var_errors = [app.media_var_errors, double(app.media_var_error(1))];
            app.cell_var_errors = [app.cell_var_errors, double(app.cell_var_error(1))];
            app.device_var_errors = [app.device_var_errors, double(app.device_var_error(1))];

            plot(app.UIAxesPlot1, 1:length(app.mean_errors), app.mean_errors, "Color", "green", "DisplayName", "Mean Errors");
            hold(app.UIAxesPlot1, 'on');
            plot(app.UIAxesPlot1, 1:length(app.mean_errors), app.media_errors, "Color", "blue", "DisplayName", "Media Errors");
            hold(app.UIAxesPlot1, 'on');
            plot(app.UIAxesPlot1, 1:length(app.mean_errors), app.cell_errors, "Color", "red", "DisplayName", "Cell Errors");
            hold(app.UIAxesPlot1, 'on');
            plot(app.UIAxesPlot1, 1:length(app.mean_errors), app.device_errors, "Color", "cyan", "DisplayName", "Device Errors");
            hold(app.UIAxesPlot1, 'on');
            legend(app.UIAxesPlot1, 'show');
            hold(app.UIAxesPlot1, 'off');

            plot(app.UIAxesPlot2, 1:length(app.mean_errors), app.mean_var_errors, "Color", "green", "DisplayName", "Mean Var Errors");
            hold(app.UIAxesPlot2, 'on');
            plot(app.UIAxesPlot2, 1:length(app.mean_errors), app.media_var_errors, "Color", "blue", "DisplayName", "Media Var Errors");
            hold(app.UIAxesPlot2, 'on');
            plot(app.UIAxesPlot2, 1:length(app.mean_errors), app.cell_var_errors, "Color", "red", "DisplayName", "Cell Var Errors");
            hold(app.UIAxesPlot2, 'on');
            plot(app.UIAxesPlot2, 1:length(app.mean_errors), app.device_var_errors, "Color", "cyan", "DisplayName", "Device Var Errors");
            hold(app.UIAxesPlot2, 'on');          
            legend(app.UIAxesPlot2, 'show');
            hold(app.UIAxesPlot2, 'off');
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            terminate(pyenv);
            pyenv('Version', ... 
            '/home/ameyasu/cuda_ws/src/SyMBac/.env/bin/python3.10', ... 
            'ExecutionMode','OutOfProcess');
            save_dir = app.DirEditField.Value;
            n_samples = app.numberEditField.Value;
            app.imagenumberSlider.Limits = [1, length(dir(fullfile(save_dir, "convolutions", "*.tif")))+n_samples];

            trench_length = app.TrenchLengthEditField.Value;
            trench_width = app.TrenchWidthEditField.Value;
            cell_length = app.CellLengthEditField.Value;
            cell_length_var = app.CellLengthVarEditField.Value;
            cell_width = app.CellWidthEditField.Value;
            cell_width_var = app.CellWidthVarEditField.Value;
            disp(trench_length)
            radius = app.RadiusEditField.Value;
            wavelength = app.WavelengthEditField.Value;
            numerical_aperture = app.NumericalApertureEditField.Value;
            refractive_index = app.RefractiveIndexEditField.Value;
            pix2mic = app.PixMicroConversionEditField.Value;
            apodisation_sigma = app.ApodisationSigmaEditField.Value;
            pyrunfile("./symbac_generate.py",  save_dir=save_dir, n_samples=py.int(n_samples), cell_length=py.float(cell_length), cell_length_var=py.float(cell_length_var), cell_width=py.float(cell_width), cell_width_var=py.float(cell_width_var), trench_length=py.float(trench_length), trench_width=py.float(trench_width), radius=py.int(radius), wavelength=py.float(wavelength), numerical_aperture=py.float(numerical_aperture), refractive_index=py.float(refractive_index), pix2mic=py.float(pix2mic), apodisation_sigma=py.int(apodisation_sigma));
        end

        % Value changed function: imagenumberSlider
        function imagenumberSliderValueChanged(app, event)
            imageNumber = uint32(app.imagenumberSlider.Value);
            save_dir = app.DirEditField.Value;
            imgs = dir(fullfile(save_dir, "convolutions", "*.tif"));
            img = imread(fullfile(save_dir, "convolutions", imgs(imageNumber).name));
            app.Image.ImageSource = uint16(cat(3, img, img, img));
            masks = dir(fullfile(save_dir, "masks", "*.tif"));
            mask = imread(fullfile(save_dir, "masks", masks(imageNumber).name))*255;
            app.Image_2.ImageSource = uint8(cat(3, mask, mask, mask));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxesPlot1 = uiaxes(app.GridLayout);
            title(app.UIAxesPlot1, 'Synthetic Image Quality')
            xlabel(app.UIAxesPlot1, 't')
            zlabel(app.UIAxesPlot1, 'Z')
            app.UIAxesPlot1.XTick = [0 20 40 60 80 100];
            app.UIAxesPlot1.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Real Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Synthetic Image')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxesPlot2 = uiaxes(app.GridLayout);
            title(app.UIAxesPlot2, 'Synthetic Image Quality')
            xlabel(app.UIAxesPlot2, 't')
            zlabel(app.UIAxesPlot2, 'Z')
            app.UIAxesPlot2.XTick = [0 20 40 60 80 100];
            app.UIAxesPlot2.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.DrawDeviceButton = uibutton(app.GridLayout, 'push');
            app.DrawDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @DrawDeviceButtonPushed, true);
            app.DrawDeviceButton.Text = 'Draw Device';
            app.SubmitButton = uibutton(app.GridLayout, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.LoadImagesButton = uibutton(app.GridLayout, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.DrawCellButton = uibutton(app.GridLayout, 'push');
            app.DrawCellButton.ButtonPushedFcn = createCallbackFcn(app, @DrawCellButtonPushed, true);
            app.DrawCellButton.Text = 'Draw Cell';
            app.DrawMediaButton = uibutton(app.GridLayout, 'push');
            app.DrawMediaButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMediaButtonPushed, true);
            app.DrawMediaButton.Text = 'Draw Media';
            app.Image = uiimage(app.GridLayout);
            app.GenerateButton = uibutton(app.GridLayout, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Image_2 = uiimage(app.GridLayout);
            app.TrenchLengthEditFieldLabel = uilabel(app.GridLayout);
            app.TrenchLengthEditFieldLabel.Text = 'Trench Length';
            app.TrenchLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.TrenchLengthEditField.Value = 14;
            app.TrenchWidthEditFieldLabel = uilabel(app.GridLayout);
            app.TrenchWidthEditFieldLabel.Text = 'Trench Width';
            app.TrenchWidthEditField = uieditfield(app.GridLayout, 'numeric');
            app.TrenchWidthEditField.Value = 1.5;
            app.TrenchParametersLabel = uilabel(app.GridLayout);
            app.TrenchParametersLabel.Text = 'Trench Parameters';
            app.CellParametersLabel = uilabel(app.GridLayout);
            app.CellParametersLabel.Text = 'Cell Parameters';
            app.PointSpreadFunctionLabel_2 = uilabel(app.GridLayout);
            app.PointSpreadFunctionLabel_2.Text = 'Point Spread Function';
            app.defocusSliderLabel = uilabel(app.GridLayout);
            app.defocusSliderLabel.Text = 'defocus';
            app.defocusSlider = uislider(app.GridLayout);
            app.defocusSlider.Limits = [0 10];
            app.defocusSlider.ValueChangedFcn = createCallbackFcn(app, @scenenumberSliderValueChanged, true);
            app.scenenumberSliderLabel = uilabel(app.GridLayout);
            app.scenenumberSliderLabel.Text = 'scene number';
            app.scenenumberSlider = uislider(app.GridLayout);
            app.scenenumberSlider.ValueChangedFcn = createCallbackFcn(app, @scenenumberSliderValueChanged, true);
            app.sigmaSliderLabel = uilabel(app.GridLayout);
            app.sigmaSliderLabel.Text = 'sigma';
            app.sigmaSlider = uislider(app.GridLayout);
            app.sigmaSlider.Limits = [0 1];
            app.sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @scenenumberSliderValueChanged, true);
            app.devicemultiplierSliderLabel = uilabel(app.GridLayout);
            app.devicemultiplierSliderLabel.Text = 'device multiplier';
            app.devicemultiplierSlider = uislider(app.GridLayout);
            app.devicemultiplierSlider.Limits = [-300 300];
            app.devicemultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @scenenumberSliderValueChanged, true);
            app.devicemultiplierSlider.ValueChangingFcn = createCallbackFcn(app, @scenenumberSliderValueChanged, true);
            app.cellmultiplierSliderLabel = uilabel(app.GridLayout);
            app.cellmultiplierSliderLabel.Text = 'cell multiplier';
            app.cellmultiplierSlider = uislider(app.GridLayout);
            app.cellmultiplierSlider.Limits = [-30 30];
            app.cellmultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @scenenumberSliderValueChanged, true);
            app.mediamultiplierSliderLabel = uilabel(app.GridLayout);
            app.mediamultiplierSliderLabel.Text = 'media multiplier';
            app.mediamultiplierSlider = uislider(app.GridLayout);
            app.mediamultiplierSlider.Limits = [-300 300];
            app.mediamultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @scenenumberSliderValueChanged, true);
            app.AbsolutepathforrealimagesforreferenceTextAreaLabel = uilabel(app.GridLayout);
            app.AbsolutepathforrealimagesforreferenceTextAreaLabel.Text = 'Absolute path for real images for reference';
            app.AbsolutepathforrealimagesforreferenceTextArea = uitextarea(app.GridLayout);
            app.DirEditFieldLabel = uilabel(app.GridLayout);
            app.DirEditFieldLabel.Text = 'Dir';
            app.DirEditField = uieditfield(app.GridLayout, 'text');
            app.DirEditField.Value = './pseudo_train/';
            app.imagenumberSliderLabel = uilabel(app.GridLayout);
            app.imagenumberSliderLabel.Text = 'image number';
            app.imagenumberSlider = uislider(app.GridLayout);
            app.imagenumberSlider.ValueChangedFcn = createCallbackFcn(app, @imagenumberSliderValueChanged, true);
            app.numberEditFieldLabel = uilabel(app.GridLayout);
            app.numberEditFieldLabel.Text = 'number';
            app.numberEditField = uieditfield(app.GridLayout, 'numeric');
            app.numberEditField.Limits = [1 1000];
            app.numberEditField.Value = 500;
            app.CellLengthEditFieldLabel = uilabel(app.GridLayout);
            app.CellLengthEditFieldLabel.Text = 'Cell Length';
            app.CellLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.CellLengthEditField.Value = 3.5;
            app.CellLengthVarEditFieldLabel = uilabel(app.GridLayout);
            app.CellLengthVarEditFieldLabel.Text = 'Cell Length Var';
            app.CellLengthVarEditField = uieditfield(app.GridLayout, 'numeric');
            app.CellLengthVarEditField.Value = 0.3;
            app.CellWidthEditFieldLabel = uilabel(app.GridLayout);
            app.CellWidthEditFieldLabel.Text = 'Cell Width';
            app.CellWidthEditField = uieditfield(app.GridLayout, 'numeric');
            app.CellWidthEditField.Value = 1;
            app.CellWidthVarEditFieldLabel = uilabel(app.GridLayout);
            app.CellWidthVarEditFieldLabel.Text = 'Cell Width Var';
            app.CellWidthVarEditField = uieditfield(app.GridLayout, 'numeric');
            app.CellWidthVarEditField.Value = 0.05;
            app.RadiusEditFieldLabel = uilabel(app.GridLayout);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.RadiusEditField.Value = 50;
            app.WavelengthEditFieldLabel = uilabel(app.GridLayout);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.WavelengthEditField.Value = 0.75;
            app.NumericalApertureEditFieldLabel = uilabel(app.GridLayout);
            app.NumericalApertureEditFieldLabel.Text = 'Numerical Aperture';
            app.NumericalApertureEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumericalApertureEditField.Value = 1.2;
            app.RefractiveIndexEditFieldLabel = uilabel(app.GridLayout);
            app.RefractiveIndexEditFieldLabel.Text = 'Refractive Index';
            app.RefractiveIndexEditField = uieditfield(app.GridLayout, 'numeric');
            app.RefractiveIndexEditField.Value = 1.3;
            app.PixMicroConversionEditFieldLabel = uilabel(app.GridLayout);
            app.PixMicroConversionEditFieldLabel.Text = 'Pix-Micro Conversion';
            app.PixMicroConversionEditField = uieditfield(app.GridLayout, 'numeric');
            app.PixMicroConversionEditField.Value = 0.065;
            app.ApodisationSigmaEditFieldLabel = uilabel(app.GridLayout);
            app.ApodisationSigmaEditFieldLabel.Text = 'Apodisation Sigma';
            app.ApodisationSigmaEditField = uieditfield(app.GridLayout, 'numeric');
            app.ApodisationSigmaEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end