classdef finetuning_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        StateTextArea                   matlab.ui.control.TextArea
        StateTextAreaLabel              matlab.ui.control.Label
        TestDataFolderEditField         matlab.ui.control.EditField
        TestDataFolderEditFieldLabel    matlab.ui.control.Label
        TotalNumberofFramesEditField    matlab.ui.control.EditField
        TotalNumberofFramesEditFieldLabel  matlab.ui.control.Label
        CellDivisionTimeNumberofFramesEditField  matlab.ui.control.EditField
        CellDivisionTimeNumberofFramesEditFieldLabel  matlab.ui.control.Label
        InitialPretrainedModelDropDown  matlab.ui.control.DropDown
        InitialPretrainedModelDropDownLabel  matlab.ui.control.Label
        CustomizedpipelineSwitch        matlab.ui.control.Switch
        CustomizedpipelineSwitchLabel   matlab.ui.control.Label
        RobustPseudolabelingSwitch      matlab.ui.control.Switch
        RobustPseudolabelingSwitchLabel  matlab.ui.control.Label
        ContinuoustuningSwitch          matlab.ui.control.Switch
        ContinuoustuningSwitchLabel     matlab.ui.control.Label
        PseudoonlySwitch                matlab.ui.control.Switch
        PseudoonlySwitchLabel           matlab.ui.control.Label
        SynthonlySwitch                 matlab.ui.control.Switch
        SynthonlySwitchLabel            matlab.ui.control.Label
        SynthPseudoRatioMultiplierSlider  matlab.ui.control.Slider
        SynthPseudoRatioMultiplierLabel  matlab.ui.control.Label
        PatienceSlider                  matlab.ui.control.Slider
        PatienceSliderLabel             matlab.ui.control.Label
        StrideSlider                    matlab.ui.control.Slider
        StrideSliderLabel               matlab.ui.control.Label
        OptimizeSyntheticTraiingDataButton  matlab.ui.control.StateButton
        TerminateButton                 matlab.ui.control.StateButton
        RunButton                       matlab.ui.control.StateButton
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        lossArray = [];
        evaluationArray = [];
        IoUArray = [];
        delaunayArray = [];
        terminated = false;
    end
    
    methods (Access = public)
        function trainingLossObtained(app, loss)
            app.lossArray = [app.lossArray, loss];
            cla(app.UIAxes);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, 1:length(app.lossArray), app.lossArray, "Color", "green", "DisplayName", "Training Loss");
            hold(app.UIAxes, "on");
            legend(app.UIAxes, 'show');
            hold(app.UIAxes, "off");
            drawnow;
            pause(0.1);
        end

        function evaluationResultObtained(app, error_rate)
            app.evaluationArray = [app.evaluationArray, error_rate];
            cla(app.UIAxes_2);
            hold(app.UIAxes_2, "on");
            plot(app.UIAxes_2, 1:length(app.evaluationArray), app.evaluationArray);
            hold(app.UIAxes_2, "off");
            drawnow;
            pause(0.1);
        end
    end
    
    methods (Access = private)
        function error_rates_averaged_over_trenches= evaluateTunedModel(app, time_frame, window_length, test_trenches)
            error_rates_averaged_over_trenches = 0;
            for i = 1:length(test_trenches)
                masks = dir(fullfile("./complete_timeseries", test_trenches(i).name, "pseudo/masks/*.tif"));
                masks = masks(time_frame - window_length: time_frame);
                error_rates_per_trench = 0;
                n_cells = 0;
                for j = 1:length(masks)-1
                    labels = bwlabel(uint32(imread(fullfile("./complete_timeseries", test_trenches(i).name, "pseudo/masks", masks(j).name))), 4);
                    next_labels = bwlabel(uint32(imread(fullfile("./complete_timeseries", test_trenches(i).name, "pseudo/masks", masks(j+1).name))), 4);
                    error_start = 1;
                    error_end = 1;
                    for label = 1:max(labels, [], "all")
                        overlapping = (labels == label) .* (next_labels == label);
                        if (nnz(overlapping)< 0.5*nnz(labels == label))
                            error_start = label;
                        end
                    end
                    labels = bwlabel(rot90(uint32(imread(fullfile("./complete_timeseries", test_trenches(i).name, "pseudo/masks", masks(j).name))), 2), 4);
                    next_labels = bwlabel(rot90(uint32(imread(fullfile("./complete_timeseries", test_trenches(i).name, "pseudo/masks", masks(j+1).name))), 2), 4);
                    for label = 1:max(labels, [], "all")
                        overlapping = (labels == label) .* (next_labels == label);
                        if (nnz(overlapping)< 0.5*nnz(labels == label))
                            error_end = label;
                        end
                    end
                    error_rates_per_trench = error_rates_per_trench + abs(error_end - error_start);
                    n_cells = n_cells + 1;
                end
                error_rates_averaged_over_trenches = error_rates_averaged_over_trenches + error_rates_per_trench/n_cells;
            end
            error_rates_averaged_over_trenches = error_rates_averaged_over_trenches/(length(test_trenches));
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            app.StateTextArea.Value = "Initializing ...";
            
            terminate(pyenv);
            pyenv('Version', ... 
            '/home/ameyasu/cuda_ws/bin/python', ... 
            'ExecutionMode','OutOfProcess');
            components = [app.RunButton, app.TestDataFolderEditField, app.TotalNumberofFramesEditField, app.CellDivisionTimeNumberofFramesEditField, app.InitialPretrainedModelDropDown, app.CustomizedpipelineSwitch, app.RobustPseudolabelingSwitch, app.ContinuoustuningSwitch, app.PseudoonlySwitch, app.SynthonlySwitch, app.SynthPseudoRatioMultiplierSlider, app.PatienceSlider, app.StrideSlider];
            app.terminated = false;
            set(components, 'Enable', "off");
            test_data_folder = app.TestDataFolderEditField.Value;
            stride = int32(app.StrideSlider.Value);
            patience = int32(app.PatienceSlider.Value);
            ratio_multiplier = app.SynthPseudoRatioMultiplierSlider.Value;
            pretrained_model = app.InitialPretrainedModelDropDown.Value;
            total_frame_number = app.TotalNumberofFramesEditField.Value;
            
            switch_names = ["continuous_tuning", "robust_pseudo_labeling", "synth_only", "pseudo_only", "customized_pipeline"];
            init_values = [false, false, false, false, false];
            switch_states = [app.ContinuoustuningSwitch.Value, app.RobustPseudolabelingSwitch.Value, app.SynthonlySwitch.Value, app.PseudoonlySwitch.Value, app.CustomizedpipelineSwitch.Value];
            switch_values = dictionary(switch_names, init_values);
            switches = dictionary(switch_names,  switch_states);
            for key=keys(switches)
                if (switches(key) == "Off")
                    switch_values(key) = false;
                else
                    switch_values(key) = true;
                end
            end
            
            pseudoLabeling = PseudoLabeling("model_name","DS/test_net_1.mat");
            test_trenches = dir(fullfile(test_data_folder));
            test_trenches = test_trenches(3:length(test_trenches));
            one_test_trench = dir(fullfile(test_data_folder, test_trenches(1).name, "pseudo/*.tif"));
            number_of_trenches = length(test_trenches);
            timeseries_length = length(one_test_trench);
            time_frame = 1;
            prev_cell_statistics = [3.5 0.9, 0.1, 0.01];
            disp(number_of_trenches);
            disp(timeseries_length);
            disp(test_trenches);
            disp(one_test_trench);
            initialized = false;
            while true
                app.StateTextArea.Value = "Segmenting, producing Pseudo-labels ...";
                if (time_frame >= timeseries_length)
                    break;
                end
                delete(fullfile('pseudo_test/convolutions/*.tif'));
                delete(fullfile('pseudo_test/masks/*.tif'));
                
                if (time_frame > timeseries_length - stride)
                    for i = 0:(timeseries_length - time_frame)-1
                        for trench_i = 1:number_of_trenches
                            %{
                            disp("copying from")
                            disp(fullfile(test_data_folder, test_trenches(trench_i).name, "pseudo/", [repmat('0', 1, 5 - numel(num2str(time_frame + i))), num2str(time_frame + i)] + ".tif"))
                            disp("to")
                            disp(fullfile("pseudo_test/convolutions", [repmat('0', 1, 5 - numel(num2str(number_of_trenches*i + time_frame + trench_i))), num2str(number_of_trenches*i + time_frame + trench_i)] + ".tif"))
                            %}
                            copyfile(fullfile(test_data_folder, test_trenches(trench_i).name, "pseudo/", [repmat('0', 1, 5 - numel(num2str(time_frame + i))), num2str(time_frame + i)] + ".tif"), fullfile("pseudo_test/convolutions", [repmat('0', 1, 5 - numel(num2str(number_of_trenches*i + time_frame + trench_i))), num2str(number_of_trenches*i + time_frame + trench_i - 2)] + ".tif"));
                        end
                    end
                else
                    for i = 0:stride-1
                        for trench_i = 1:number_of_trenches
                            %{
                            disp("copying from")
                            disp(fullfile(test_data_folder, test_trenches(trench_i).name, "pseudo/", [repmat('0', 1, 5 - numel(num2str(time_frame + i))), num2str(time_frame + i)] + ".tif"))
                            disp("to")
                            disp(fullfile("pseudo_test/convolutions", [repmat('0', 1, 5 - numel(num2str(number_of_trenches*i + time_frame + trench_i))), num2str(number_of_trenches*i + time_frame + trench_i)] + ".tif"))
                            %}
                            copyfile(fullfile(test_data_folder, test_trenches(trench_i).name, "pseudo/", [repmat('0', 1, 5 - numel(num2str(time_frame + i))), num2str(time_frame + i)] + ".tif"), fullfile("pseudo_test/convolutions", [repmat('0', 1, 5 - numel(num2str(number_of_trenches*i + time_frame + trench_i))), num2str(number_of_trenches*i + time_frame + trench_i - 2)] + ".tif"));
                        end
                    end
                end

                pseudoLabeling.evaluate(initialized);
                app.StateTextArea.Value = "Comparing current data with knowledge ...";
                temp_mask_filenames = dir(fullfile("pseudo_test/masks", "*.tif"));
                disp(size(imread(fullfile("pseudo_test/masks", temp_mask_filenames(1).name))))
                temp_masks = zeros( length(temp_mask_filenames), size(imread(fullfile("pseudo_test/masks", temp_mask_filenames(1).name)), 1), size(imread(fullfile("pseudo_test/masks", temp_mask_filenames(1).name)), 2) );

                disp(temp_mask_filenames);
                for i=1:numel(temp_mask_filenames)
                    temp_masks(i, :, :) = uint32(imread(fullfile("pseudo_test/masks", temp_mask_filenames(i).name)));
                end
                cell_statistics = pseudoLabeling.cell_statistics(temp_masks);
                if (norm(cell_statistics(1:2) - prev_cell_statistics(1:2))/norm(prev_cell_statistics(1:2)) > 0.2 & (0.8*cell_statistics(1) < 3.5 | 0.8*cell_statistics(1) > 6.0))
                    app.StateTextArea.Value = "Generating synthetic data for fine-tuning ...";
                    delete(fullfile('pseudo_train/convolutions/*.tif'));
                    delete(fullfile('pseudo_train/masks/*.tif'));

                    prev_cell_statistics = cell_statistics;
                    disp(cell_statistics);
                    %pseudoLabeling.symbac_generation("./pseudo_train", 100, cell_statistics);
                    terminate(pyenv);
                    pyenv('Version', ... 
                    '/home/ameyasu/cuda_ws/src/SyMBac/.env/bin/python3.10', ... 
                    'ExecutionMode','OutOfProcess');
                    cell_statistics(3) = 0.64*0.5*cell_statistics(3);
                    cell_statistics(4) = 0.64*0.5*cell_statistics(4);
                    if (cell_statistics(3) > 0.05*cell_statistics(1))
                        cell_statistics(3) = 0.05*cell_statistics(1);
                    end
                    if (cell_statistics(4) > 0.01*cell_statistics(2))
                        cell_statistics(4) = 0.01*cell_statistics(2);
                    end
                    pyrunfile("./symbac_generate.py",  save_dir="./pseudo_train", n_samples=py.int(300), cell_length = py.float(cell_statistics(1)), cell_width=py.float(cell_statistics(2)), cell_length_var=py.float(cell_statistics(3)), cell_width_var=py.float(cell_statistics(4)));
                    %pyrunfile("./symbac_generate.py",  save_dir="./pseudo_train", n_samples=py.int(100), length_mean = py.float(3), width_mean=py.float(0.9), length_var=py.float(0.1), width_var=py.float(0.01));
                    app.StateTextArea.Value = "Fine-tuning model ...";
                    dataSetDir = './pseudo_train';
                    
                    images = dir(fullfile(dataSetDir, "convolutions", "*.tif"));
                    labels = dir(fullfile(dataSetDir, "masks", "*.tif"));
                    
                    max_x_translation = 20;
                    max_y_translation = 10;
                    for i = 1:length(images)
                        disp(images(i).name);
                        disp(labels(i).name);
                        original_image = uint16(imread(fullfile(dataSetDir, "convolutions", images(i).name)));
                        original_label = uint16(imread(fullfile(dataSetDir, "masks", labels(i).name)));
                        x_translation = randi([-max_x_translation, max_x_translation]);
                        y_translation = randi([-max_y_translation, max_y_translation]);
                        cropped_image = [];
                        cropped_label = [];
                        if (x_translation > 0)
                            if (y_translation > 0)
                                cropped_image = original_image(x_translation:end, y_translation:end);
                                cropped_label = original_label(x_translation:end, y_translation:end);
                            else
                                cropped_image = original_image(x_translation:end, 1:end+y_translation);
                                cropped_label = original_label(x_translation:end, 1:end+y_translation);
                            end
                        else
                            if (y_translation > 0)
                                cropped_image = original_image(1:end+x_translation, y_translation:end);
                                cropped_label = original_label(1:end+x_translation, y_translation:end);
                            else
                                cropped_image = original_image(1:end+x_translation, 1:end+y_translation);
                                cropped_label = original_label(1:end+x_translation, 1:end+y_translation);
                            end
                        end
                        cropped_label = imresize(cropped_label, size(original_label), "nearest");
                        cropped_image = imresize(cropped_image, size(original_image), "nearest");
                        imwrite(cropped_image, fullfile(dataSetDir, "convolutions", images(i).name));
                        imwrite(cropped_label, fullfile(dataSetDir, "masks", labels(i).name));
                    end
                    copyfile("./synth_99999_conv.tif", "./pseudo_train/convolutions/synth_99999.tif");
                    copyfile("./synth_99999_mask.tif", "./pseudo_train/masks/synth_99999.tif");
                    pseudoLabeling.train(5, initialized, app);

                    initialized = true;
                    delete(fullfile("pseudo_test/masks", "*.tif"));
                    pseudoLabeling.evaluate(initialized);

                    copyfile(fullfile("pseudo_test/convolutions", "*.tif"), fullfile("pseudo_train/convolutions/"));
                    copyfile(fullfile("pseudo_test/masks", "*.tif"), fullfile("pseudo_train/masks/"));
                    
                    pseudoLabeling.train(2, initialized, app);

                    delete(fullfile('pseudo_test/masks/*.tif'));
                    pseudoLabeling.evaluate(initialized);
                    
                    temp_masks = zeros( length(temp_mask_filenames), size(imread(fullfile("pseudo_test/masks", temp_mask_filenames(1).name)), 1), size(imread(fullfile("pseudo_test/masks", temp_mask_filenames(1).name)), 2) );
                    disp(temp_mask_filenames);
                    for i=1:numel(temp_mask_filenames)
                        temp_masks(i, :, :) = uint32(imread(fullfile("pseudo_test/masks", temp_mask_filenames(i).name)));
                    end
                    
                end
                
                for i = 1:size(temp_masks, 1)
                    j = mod(i, number_of_trenches);
                    k = idivide(uint32(i), uint32(number_of_trenches)) + uint32(time_frame);
                    imwrite(uint8(squeeze(temp_masks(uint32(i),:,:))), fullfile("complete_timeseries/", test_trenches(uint32(j + 1)).name, "pseudo/masks", [repmat('0', 1, 5 - numel(num2str(uint32(k)))), num2str(uint32(k) - 1)] + ".tif"));
                end
                
                if (time_frame > timeseries_length - stride)
                    time_frame = timeseries_length;
                    app.evaluateTunedModel(time_frame, mod(time_frame, 100), test_trenches)
                else
                    time_frame = time_frame + stride;
                    if (time_frame > 0 && mod(time_frame, 100) <= stride)
                        result = app.evaluateTunedModel(time_frame, 100, test_trenches);
                        app.evaluationResultObtained(result);
                    end
                end
                if (app.terminated)
                    break;
                end
            end

            set(components, 'Enable', "on");
            % pyrunfile("../pseudo_dev.py", test_data_folder = test_data_folder, stride=stride, patience=patience, ratio_multiplier=ratio_multiplier, pretrained_model=pretrained_model, total_frame_number = total_frame_number, continuous_tuning = switch_values("continuous_tuning"), robust_pseudo_labeling = switch_values("robust_pseudo_labeling"), synth_only = switch_values("synth_only"), pseudo_only=switch_values("pseudo_only"), customized_pipeline = switch_values("customized_pipeline"));
        end

        % Value changed function: OptimizeSyntheticTraiingDataButton
        function OptimizeSyntheticTraiingDataButtonValueChanged(app, event)
            value = app.OptimizeSyntheticTraiingDataButton.Value;
            app.DialogApp = DialogAppExample(app1);
        end

        % Value changed function: TerminateButton
        function TerminateButtonValueChanged(app, event)
            value = app.TerminateButton.Value;
            app.terminated = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1038, 1038};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {372, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.RunButton = uibutton(app.GridLayout2, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.TerminateButton = uibutton(app.GridLayout2, 'state');
            app.TerminateButton.ValueChangedFcn = createCallbackFcn(app, @TerminateButtonValueChanged, true);
            app.TerminateButton.Text = 'Terminate';
            app.OptimizeSyntheticTraiingDataButton = uibutton(app.GridLayout2, 'state');
            app.OptimizeSyntheticTraiingDataButton.ValueChangedFcn = createCallbackFcn(app, @OptimizeSyntheticTraiingDataButtonValueChanged, true);
            app.OptimizeSyntheticTraiingDataButton.Text = 'Optimize Synthetic Traiing Data';
            app.StrideSliderLabel = uilabel(app.GridLayout2);
            app.StrideSliderLabel.Text = 'Stride';
            app.StrideSlider = uislider(app.GridLayout2);
            app.PatienceSliderLabel = uilabel(app.GridLayout2);
            app.PatienceSliderLabel.Text = 'Patience';
            app.PatienceSlider = uislider(app.GridLayout2);
            app.SynthPseudoRatioMultiplierLabel = uilabel(app.GridLayout2);
            app.SynthPseudoRatioMultiplierLabel.Text = {'Synth-Pseudo '; 'Ratio Multiplier'};
            app.SynthPseudoRatioMultiplierSlider = uislider(app.GridLayout2);
            app.SynthonlySwitchLabel = uilabel(app.GridLayout2);
            app.SynthonlySwitchLabel.Text = 'Synth-only';
            app.SynthonlySwitch = uiswitch(app.GridLayout2, 'slider');
            app.PseudoonlySwitchLabel = uilabel(app.GridLayout2);
            app.PseudoonlySwitchLabel.Text = 'Pseudo-only';
            app.PseudoonlySwitch = uiswitch(app.GridLayout2, 'slider');
            app.ContinuoustuningSwitchLabel = uilabel(app.GridLayout2);
            app.ContinuoustuningSwitchLabel.Text = 'Continuous-tuning';
            app.ContinuoustuningSwitch = uiswitch(app.GridLayout2, 'slider');
            app.RobustPseudolabelingSwitchLabel = uilabel(app.GridLayout2);
            app.RobustPseudolabelingSwitchLabel.Text = 'Robust-Pseudo-labeling';
            app.RobustPseudolabelingSwitch = uiswitch(app.GridLayout2, 'slider');
            app.CustomizedpipelineSwitchLabel = uilabel(app.GridLayout2);
            app.CustomizedpipelineSwitchLabel.Text = 'Customized-pipeline';
            app.CustomizedpipelineSwitch = uiswitch(app.GridLayout2, 'slider');
            app.InitialPretrainedModelDropDownLabel = uilabel(app.GridLayout2);
            app.InitialPretrainedModelDropDownLabel.Text = 'Initial Pre-trained Model';
            app.InitialPretrainedModelDropDown = uidropdown(app.GridLayout2);
            app.CellDivisionTimeNumberofFramesEditFieldLabel = uilabel(app.GridLayout2);
            app.CellDivisionTimeNumberofFramesEditFieldLabel.Text = {'Cell Division Time '; '(Number of Frames)'};
            app.CellDivisionTimeNumberofFramesEditField = uieditfield(app.GridLayout2, 'text');
            app.TotalNumberofFramesEditFieldLabel = uilabel(app.GridLayout2);
            app.TotalNumberofFramesEditFieldLabel.Text = 'Total Number of Frames';
            app.TotalNumberofFramesEditField = uieditfield(app.GridLayout2, 'text');
            app.TestDataFolderEditFieldLabel = uilabel(app.GridLayout2);
            app.TestDataFolderEditFieldLabel.Text = 'Test Data Folder';
            app.TestDataFolderEditField = uieditfield(app.GridLayout2, 'text');
            app.StateTextAreaLabel = uilabel(app.GridLayout2);
            app.StateTextAreaLabel.Text = 'State';
            app.StateTextArea = uitextarea(app.GridLayout2);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Training Progress')
            xlabel(app.UIAxes, 'Iteration')
            ylabel(app.UIAxes, 'Loss')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes_2, 'Real-time Benchmarking')
            xlabel(app.UIAxes_2, 'Frame Number')
            ylabel(app.UIAxes_2, 'Drifting in Cell CoM Prediction from Ground-truth (Pixels)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Image Sample')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2_2, 'Segmentation Sample')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.GridLayout3);
            title(app.UIAxes2_3, 'Groundtruth Sample')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finetuning_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end